classdef PColor_Mchanels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptrisMonitorToolUIFigure   matlab.ui.Figure
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        RButton                     matlab.ui.control.StateButton
        RecordStatLamp              matlab.ui.control.Lamp
        RecordStatLampLabel         matlab.ui.control.Label
        ConnectionSwitch            matlab.ui.control.Switch
        ConnectionSwitchLabel       matlab.ui.control.Label
        ConnectLamp                 matlab.ui.control.Lamp
        UnconnectLabel              matlab.ui.control.Label
        OptButton                   matlab.ui.control.Button
        AutorangemodeDropDown       matlab.ui.control.DropDown
        AutorangemodeDropDownLabel  matlab.ui.control.Label
        Button_2                    matlab.ui.control.Button
        Triger                      matlab.ui.control.Button
        PaletteDropDown             matlab.ui.control.DropDown
        PaletteDropDownLabel        matlab.ui.control.Label
        RTImage                     matlab.ui.control.Image
    end

    
    properties (Access = public)
    % initialize the interface as a global variable
    IRInterface; 
     % Description
     % run flag;
     viewer_is_running=1;
     PaletteValue=8;
     IsRecording=0;
     ScaleValue=3;
     Recorddata=[];
     CurrentFrameRate=27;
     fn=1;
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Copyright (c) 2012-2017 All Rights Reserved, 
% http://www.evocortex.com      
% Evocortex GmbH                                                         
% Emilienstr. 1                                                             
% 90489 Nuremberg                                                        
% Germany    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Author: Christian Pfitzner, Evocortex GmbH
% Date:   2017-06-05
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Support: direct-sdk@optris.com
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Description: 
% This file is the minimal example to access the 
% EvoIRMatlabInterface. The output of the palette image
% is enhanced with some control possibilities, e.g. 
% switching the palette's color, range and to trigger 
% the shutter flag. % This example is tested with 
% Windows and Linux. 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
          %
           Cvalue = app.UnconnectLabel.Text;
           if strcmpi(Cvalue,'Connected')
              dlgTitle    = 'Closing window when camera connected';
              dlgQuestion = 'The camera is still connected.Do you wish to continue?';
              choice = questdlg(dlgQuestion,dlgTitle,'Yes','No', 'No');
              if strcmpi(choice, 'No')
                  return
              else
                 
                  app.IRInterface.terminate();
                  app.delete;
              end
           else
               app.delete;
           end
           
        end

        % Value changed function: PaletteDropDown
        function PaletteDropDownValueChanged(app, event)
            Pvalue = app.PaletteDropDown.Value;
%             app.IRInterface = EvoIRMatlabInterface;
          %  app.Label.Text=num2str(Pvalue);
            switch Pvalue
                
                case 'GrayBlackHot'
                    app.PaletteValue=4;
                case 'GrayWhiteHot'
                    app.PaletteValue=3;
                case 'AlarmRed'
                    app.PaletteValue=11;
                case 'AlarmGreen'
                    app.PaletteValue=5;
                case 'AlarmBlue'
                    app.PaletteValue=1;
                case 'AlarmBlueHi'
                    app.PaletteValue=2;
                case 'Iron'
                    app.PaletteValue=6;
                case 'IronHi'
                    app.PaletteValue=7;
                case 'Rainbow'
                    app.PaletteValue=9;
                case 'RainbowHi'
                    app.PaletteValue=10;
                case 'Medical'   
                    app.PaletteValue=8;
      
            end
         %  app.IRInterface.set_palette_colormap(app.PaletteValue);
            
           
        end

        % Value changed function: RButton
        function RButtonValueChanged(app, event)
            value = app.RButton.Value;
            if value==1
                app.RecordStatLamp.Color='r';
                app.IsRecording=1;
            else    
                % change stats
                app.IsRecording=0;
                app.RecordStatLamp.Color='g';
                app.IsRecording=0;
                app.fn=1;
                
             % saving recorded data--write the frames to video file.

                TmStr=clock;
                Vname=['TVideo',num2str(TmStr(4)),'-',num2str(TmStr(5)),'-',num2str(round(TmStr(6))),'.avi'];
                NewVideo=VideoWriter(Vname, 'Uncompressed AVI');
                NewVideo.FrameRate=app.CurrentFrameRate;
                open(NewVideo);
                writeVideo(NewVideo,app.Recorddata);
                

                pause(1);
                % clear buffers
                app.Recorddata=[];
                close(NewVideo);
                
            end
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
           if strcmp(value,'Off')
                app.viewer_is_running=0;
                app.IRInterface.terminate();
                app.ConnectLamp.Color='r';
                app.UnconnectLabel.FontColor='r';
                app.UnconnectLabel.Text='Camera DisConnect';
           else
               % claim the Interface (via m file)          
               app.IRInterface = EvoIRMatlabInterface;
               % enable running flag
               app.viewer_is_running=1;
                        if ~app.IRInterface.connect()  
                            app.ConnectLamp.Color='red';
                            app.UnconnectLabel.FontColor='red';
                            app.UnconnectLabel.Text='Connection failed';
                            %return
                        else 
                           app.UnconnectLabel.FontColor='green';
                           app.UnconnectLabel.Text= "Connected";
                           app.ConnectLamp.Color='green';
                           
                           while(app.viewer_is_running)
                                SValue=app.ConnectionSwitch.Value;
                                if strcmpi(SValue,'Off')
                                  app.viewer_is_running=0;
                                    app.IRInterface.terminate();
                                    app.ConnectLamp.Color='r';
                                    app.UnconnectLabel.FontColor='r';
                                    app.UnconnectLabel.Text='Camera DisConnect';
                                end
                               
%                                 RValue=app.RButton.Value;  % Record flag
                                    app.IRInterface.set_palette_colormap(app.PaletteValue);
                                    
                                    if app.ScaleValue==1
                                        app.IRInterface.set_temperature_range(app.MinEditField.Value, app.MaxEditField.Value);
                                    end
                                    app.IRInterface.set_palette_scale(app.ScaleValue);
                                    RGB = app.IRInterface.get_palette();        % grab palette image
                               %  THM = app.IRInterface.get_thermal();        % grab thermal image
                                    if app.IsRecording
                                        % save pictures to a var and then
                                        % save to video file later;
                                        % convert RGB to double frame
                                        app.Recorddata(:,:,:,app.fn)=im2double(RGB);
                                        app.fn=app.fn+1;
                                 

                                    end
                                    
                                    
                                    %    imagesc(app.RTImage, RGB);
                                   app.RTImage.ImageSource=RGB;
                                   drawnow();

                                   % grab image data
                                
                         
                           end  % end of while
                           
                        end % end of if-else
           end
        end

        % Value changed function: AutorangemodeDropDown
        function AutorangemodeDropDownValueChanged(app, event)
            Svalue = app.AutorangemodeDropDown.Value;
            
            switch Svalue
                
                case 'Manual'
                    app.ScaleValue=1;
                    app.MaxEditField.Enable=true;
                    app.MinEditField.Enable=true;
                case 'MinMax'
                    app.ScaleValue=2;
                    app.MaxEditField.Enable=0;
                    app.MinEditField.Enable=0;
                case 'Sigma1'
                    app.ScaleValue=3;
                    app.MaxEditField.Enable=0;
                    app.MinEditField.Enable=0;
                case 'Sigma3'
                    app.ScaleValue=4;
                    app.MaxEditField.Enable=0;
                    app.MinEditField.Enable=0;
              
      
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptrisMonitorToolUIFigure = uifigure('Visible', 'off');
            app.OptrisMonitorToolUIFigure.Name = 'Optris Monitor Tool';
            app.RTImage = uiimage(app.OptrisMonitorToolUIFigure);
            app.PaletteDropDownLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.PaletteDropDownLabel.Text = 'Palette:';
            app.PaletteDropDown = uidropdown(app.OptrisMonitorToolUIFigure);
            app.PaletteDropDown.Items = {'GrayBlackHot', 'GrayWhiteHot', 'AlarmRed', 'AlarmGreen', 'AlarmBlue', 'AlarmBlueHi', 'Iron', 'IronHi', 'Rainbow', 'RainbowHi', 'Medical'};
            app.PaletteDropDown.ValueChangedFcn = createCallbackFcn(app, @PaletteDropDownValueChanged, true);
            app.PaletteDropDown.Value = 'Medical';
            app.Triger = uibutton(app.OptrisMonitorToolUIFigure, 'push');
            app.Triger.Text = {'Triger'; ''};
            app.Button_2 = uibutton(app.OptrisMonitorToolUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '❌';
            app.AutorangemodeDropDownLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.AutorangemodeDropDownLabel.Text = 'Auto range mode:';
            app.AutorangemodeDropDown = uidropdown(app.OptrisMonitorToolUIFigure);
            app.AutorangemodeDropDown.Items = {'Manual', 'MinMax', 'Sigma1', 'Sigma3'};
            app.AutorangemodeDropDown.ValueChangedFcn = createCallbackFcn(app, @AutorangemodeDropDownValueChanged, true);
            app.AutorangemodeDropDown.Value = 'Sigma1';
            app.OptButton = uibutton(app.OptrisMonitorToolUIFigure, 'push');
            app.OptButton.Text = 'Opt';
            app.UnconnectLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.UnconnectLabel.Text = 'Unconnect';
            app.ConnectLamp = uilamp(app.OptrisMonitorToolUIFigure);
            app.ConnectionSwitchLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.OptrisMonitorToolUIFigure, 'slider');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.RecordStatLampLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.RecordStatLampLabel.Text = 'Record Stat';
            app.RecordStatLamp = uilamp(app.OptrisMonitorToolUIFigure);
            app.RButton = uibutton(app.OptrisMonitorToolUIFigure, 'state');
            app.RButton.ValueChangedFcn = createCallbackFcn(app, @RButtonValueChanged, true);
            app.RButton.Text = 'R';
            app.MaxEditFieldLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.OptrisMonitorToolUIFigure, 'numeric');
            app.MaxEditField.Enable = 'off';
            app.MaxEditField.Value = 60;
            app.MinEditFieldLabel = uilabel(app.OptrisMonitorToolUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.OptrisMonitorToolUIFigure, 'numeric');
            app.MinEditField.Enable = 'off';
            % Show the figure after all components are created
            app.OptrisMonitorToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PColor_Mchanels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptrisMonitorToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptrisMonitorToolUIFigure)
        end
    end
end